classdef layers_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        LoadTab                      matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        LoadImagePanel               matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        LoadButton                   matlab.ui.control.Button
        ImageTab                     matlab.ui.container.Tab
        GridLayout2_2                matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        FrameNumLabel                matlab.ui.control.Label
        DisplayedFrameLabel          matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        LayersPanel                  matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        DrawButton                   matlab.ui.control.Button
        ImageDataPanel               matlab.ui.container.Panel
        GridLayout3_2                matlab.ui.container.GridLayout
        CropSizeEditField            matlab.ui.control.NumericEditField
        CropSizeEditFieldLabel       matlab.ui.control.Label
        GridLayout10                 matlab.ui.container.GridLayout
        CropC_Y                      matlab.ui.control.NumericEditField
        CropC_X                      matlab.ui.control.NumericEditField
        CropCenterPointLabel         matlab.ui.control.Label
        CropCheckBox                 matlab.ui.control.CheckBox
        DeleteBackgroundButton       matlab.ui.control.Button
        LoadButton_2                 matlab.ui.control.Button
        ShowLegendCheckBox           matlab.ui.control.CheckBox
        ImageTitleEditField          matlab.ui.control.EditField
        ImageTitleEditFieldLabel     matlab.ui.control.Label
        ColorbarTitleEditField       matlab.ui.control.EditField
        ColorbarTitleEditFieldLabel  matlab.ui.control.Label
        ShowColorbarCheckBox         matlab.ui.control.CheckBox
        GridLayout4                  matlab.ui.container.GridLayout
        BValue                       matlab.ui.control.Spinner
        GValue                       matlab.ui.control.Spinner
        RValue                       matlab.ui.control.Spinner
        ColorForNaNLabel             matlab.ui.control.Label
        BackgroundImageLabel         matlab.ui.control.Label
        LayerDataPanel               matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        ChooseFrameSlider            matlab.ui.control.Slider
        ChooseFrameLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageLoaderComponentHandler ImageLoaderComponent
        ImageComponentHandler ImageComponentHandler
        ImageDisplayHandler ImageDisplayHandler
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %TODO addpath
            search_path = '../*/classDefinitions';
            while isempty(dir(search_path))
                search_path = ['../', search_path];
            end
            addpath(dir(search_path).folder) 
            imageBuilder=ImageBuilder;
            app.ImageDisplayHandler=ImageDisplayHandler(app,imageBuilder);
            app.ImageComponentHandler=ImageComponentHandler(app.ImageDisplayHandler,imageBuilder, app);
            app.ImageLoaderComponentHandler=ImageLoaderComponent(app,app.ImageComponentHandler,imageBuilder);
            
            %to move
            app.ImageTab.Parent=[];
%             app.ImageLoaderComponentHandler.updateStatusTable(app);
%             app.DrawButton_2.Enable=false;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ImageLoaderComponentHandler.loadImageBuilder();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ImageDisplayHandler.closeFigure();
            delete(app);
        end

        % Value changed function: ChooseFrameSlider
        function ChooseFrameSliderValueChanged(app, event)
            value = app.ChooseFrameSlider.Value;
            app.ImageDisplayHandler.setFrame(round(value));
            app.ImageComponentHandler.setDisplayedFrameNumber(round(value));

        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.ImageComponentHandler.renderFromInput;
        end

        % Callback function
        function LoadButton_3Pushed(app, event)
            app.ImageLoaderComponentHandler.loadBackground(app);
        end

        % Callback function
        function DrawButton_2Pushed(app, event)
            app.ImageLoaderComponentHandler.showLoadedData(app);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.ImageComponentHandler.loadBackground();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.ImageComponentHandler.saveFigures();
        end

        % Button pushed function: DeleteBackgroundButton
        function DeleteBackgroundButtonPushed(app, event)
            app.ImageComponentHandler.deleteBackground();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.GridLayout2 = uigridlayout(app.LoadTab);
            app.LoadImagePanel = uipanel(app.GridLayout2);
            app.LoadImagePanel.Title = 'Load Image';
            app.GridLayout6 = uigridlayout(app.LoadImagePanel);
            app.LoadButton = uibutton(app.GridLayout6, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout2_2 = uigridlayout(app.ImageTab);
            app.ChooseFrameLabel = uilabel(app.GridLayout2_2);
            app.ChooseFrameLabel.Text = 'Choose Frame:';
            app.ChooseFrameSlider = uislider(app.GridLayout2_2);
            app.ChooseFrameSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseFrameSliderValueChanged, true);
            app.LayerDataPanel = uipanel(app.GridLayout2_2);
            app.LayerDataPanel.Title = 'Layer Data';
            app.GridLayout3 = uigridlayout(app.LayerDataPanel);
            app.ImageDataPanel = uipanel(app.GridLayout2_2);
            app.ImageDataPanel.Title = 'Image Data';
            app.GridLayout3_2 = uigridlayout(app.ImageDataPanel);
            app.BackgroundImageLabel = uilabel(app.GridLayout3_2);
            app.BackgroundImageLabel.Text = 'Background Image';
            app.ColorForNaNLabel = uilabel(app.GridLayout3_2);
            app.ColorForNaNLabel.Text = 'Color For NaN';
            app.GridLayout4 = uigridlayout(app.GridLayout3_2);
            app.RValue = uispinner(app.GridLayout4);
            app.RValue.Step = 0.1;
            app.RValue.Limits = [0 1];
            app.GValue = uispinner(app.GridLayout4);
            app.GValue.Step = 0.1;
            app.GValue.Limits = [0 1];
            app.BValue = uispinner(app.GridLayout4);
            app.BValue.Step = 0.1;
            app.BValue.Limits = [0 1];
            app.ShowColorbarCheckBox = uicheckbox(app.GridLayout3_2);
            app.ShowColorbarCheckBox.Text = 'Show Colorbar';
            app.ColorbarTitleEditFieldLabel = uilabel(app.GridLayout3_2);
            app.ColorbarTitleEditFieldLabel.Text = 'Colorbar Title';
            app.ColorbarTitleEditField = uieditfield(app.GridLayout3_2, 'text');
            app.ImageTitleEditFieldLabel = uilabel(app.GridLayout3_2);
            app.ImageTitleEditFieldLabel.Text = 'Image Title';
            app.ImageTitleEditField = uieditfield(app.GridLayout3_2, 'text');
            app.ShowLegendCheckBox = uicheckbox(app.GridLayout3_2);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.LoadButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.DeleteBackgroundButton = uibutton(app.GridLayout3_2, 'push');
            app.DeleteBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBackgroundButtonPushed, true);
            app.DeleteBackgroundButton.Text = 'Delete Background';
            app.CropCheckBox = uicheckbox(app.GridLayout3_2);
            app.CropCheckBox.Text = 'Crop';
            app.CropCenterPointLabel = uilabel(app.GridLayout3_2);
            app.CropCenterPointLabel.Text = 'Crop Center Point';
            app.GridLayout10 = uigridlayout(app.GridLayout3_2);
            app.CropC_X = uieditfield(app.GridLayout10, 'numeric');
            app.CropC_Y = uieditfield(app.GridLayout10, 'numeric');
            app.CropSizeEditFieldLabel = uilabel(app.GridLayout3_2);
            app.CropSizeEditFieldLabel.Text = 'Crop Size';
            app.CropSizeEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.DrawButton = uibutton(app.GridLayout2_2, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.LayersPanel = uipanel(app.GridLayout2_2);
            app.LayersPanel.Title = 'Layers';
            app.GridLayout8 = uigridlayout(app.LayersPanel);
            app.SaveButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GridLayout9 = uigridlayout(app.GridLayout2_2);
            app.DisplayedFrameLabel = uilabel(app.GridLayout9);
            app.DisplayedFrameLabel.Text = 'Displayed Frame:';
            app.FrameNumLabel = uilabel(app.GridLayout9);
            app.FrameNumLabel.Text = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layers_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end